classdef waterSamplerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainMenu     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Image        matlab.ui.control.Image
        LogsText     matlab.ui.control.TextArea
        LogsPanel    matlab.ui.container.Panel
        WebcamPanel  matlab.ui.container.Panel
        StopButton   matlab.ui.control.Button
        SCRAMButton  matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
    end

    properties (Access = public)
        startVision = false;
        wS_State = "Stopped";

        % Evaluates the value of the variable called "cam" in the workspace.
        % Then, assigns that value to app.cam
        cam = evalin('base', 'cam');
        %a = evalin('base', 'a');
    end

    methods (Access = private)
        
        function wS_Log(app, level, message)
            app.LogsText.Value = [sprintf("%7.3f - [%s] %s", toc, level, message); app.LogsText.Value];
        end

        function wS_Vision(app)
            while(app.startVision)
                app.Image.ImageSource = snapshot(app.cam);
                pause(0.1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tic; % Starts the timer
            drawnow % Draws the app
            app.MainMenu.WindowState = 'maximized'; % Maximizes the app

            app.StartButton.BackgroundColor = [0.39, 0.83, 0.07];
            app.SCRAMButton.BackgroundColor = [1.00, 0.00, 0.00];
            app.StopButton.BackgroundColor = [0.00, 0.45, 0.74];

            % Tries to initialize the Arduino.
            % If it fails, it crashes with a log message
            try
                %app.a = arduino();
                wS_Log(app, "INF", "Arduino initialized.");
            catch exception
                app.LogsText.FontColor = [1.00, 0.00, 0.00];
                wS_Log(app, "ERR", "Arduino failed to initialize. (Is it plugged in?) Crashing with exception...");
                rethrow(exception);
            end

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~(app.wS_State == "Started")
                app.wS_State = "Started"; % If not already, then do so

                % Sets the background color to dark green
                app.GridLayout.BackgroundColor = [0.00, 0.72, 0.00];
                app.StartButton.BackgroundColor = [0.47, 0.47, 0.47];
                app.SCRAMButton.BackgroundColor = [1.00, 0.00, 0.00];
                app.StopButton.BackgroundColor = [0.00, 0.45, 0.74];
    
                wS_Log(app, "USR", "Starting the water sampler...");

                tic; % Restarts the timer
    
                % Starts the webcam stream
                app.startVision = true;
                wS_Vision(app);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~(app.wS_State == "Stopped")
                app.wS_State = "Stopped"; % If not already, then do so

                % Sets the background color to dark blue
                app.GridLayout.BackgroundColor = [0.00, 0.34, 0.56];
                app.StartButton.BackgroundColor = [0.39, 0.83, 0.07];
                app.SCRAMButton.BackgroundColor = [1.00, 0.00, 0.00];
                app.StopButton.BackgroundColor = [0.47, 0.47, 0.47];

                wS_Log(app, "USR", "Stopping the water sampler...");
    
                % Stops the webcam
                wS_Log(app, "INF", "Stopping webcam...");
                app.startVision = false;
                pause(0.100); % Pause to ensure it does not call a deleted object
                delete(app.cam);
    
                % Returns motors to home

            end
        end

        % Button pushed function: SCRAMButton
        function SCRAMButtonPushed(app, event)
            if ~(app.wS_State == "SCRAM")
                app.wS_State = "SCRAM"; % If not already, then do so
                
                app.GridLayout.BackgroundColor = [0.80, 0.00, 0.00];
                app.StartButton.BackgroundColor = [0.39, 0.83, 0.07];
                app.SCRAMButton.BackgroundColor = [0.47, 0.47, 0.47];
                app.StopButton.BackgroundColor = [0.00, 0.45, 0.74];
                wS_Log(app, "USR", "SCRAMing the water sampler...");

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainMenu = uifigure('Visible', 'off');
            app.MainMenu.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.MainMenu);
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Starts the water sampler'};
            app.StartButton.Text = 'Start';
            app.SCRAMButton = uibutton(app.GridLayout, 'push');
            app.SCRAMButton.ButtonPushedFcn = createCallbackFcn(app, @SCRAMButtonPushed, true);
            app.SCRAMButton.Tooltip = {'Stops the water sampler instantly without resetting motor positions'};
            app.SCRAMButton.Text = 'SCRAM';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tooltip = {'Stops the water sampler and resets to default position'};
            app.StopButton.Text = 'Stop';
            app.WebcamPanel = uipanel(app.GridLayout);
            app.WebcamPanel.Title = 'Webcam';
            app.LogsPanel = uipanel(app.GridLayout);
            app.LogsPanel.Title = 'Logs';
            app.LogsText = uitextarea(app.GridLayout);
            app.LogsText.Editable = 'off';
            app.Image = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.MainMenu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waterSamplerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainMenu)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainMenu)
        end
    end
end